classdef nav_GUI_MD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        StopButton                 matlab.ui.control.Button
        DISPLAYLabel               matlab.ui.control.Label
        BrainsightButton           matlab.ui.control.Button
        LocaliteButton             matlab.ui.control.Button
        OpticalcameraLabel         matlab.ui.control.Label
        ManualLabel                matlab.ui.control.Label
        PointandclickonthescalpmaptoupdatetransducerLabel  matlab.ui.control.Label
        Step3Label                 matlab.ui.control.Label
        Step2Label                 matlab.ui.control.Label
        Step1Label                 matlab.ui.control.Label
        NUCLEIVISIBILITYLabel      matlab.ui.control.Label
        NAVIGATELabel              matlab.ui.control.Label
        LOADSAVEDATALabel          matlab.ui.control.Label
        GotobestpositionButton     matlab.ui.control.Button
        ShowscalpmaprightCheckBox  matlab.ui.control.CheckBox
        ShowscalpmapleftCheckBox   matlab.ui.control.CheckBox
        ToggleallrightCheckBox     matlab.ui.control.CheckBox
        TimetricrightButton        matlab.ui.control.Button
        TimetricleftButton         matlab.ui.control.Button
        SavefiguresButton          matlab.ui.control.Button
        ToggleallleftCheckBox      matlab.ui.control.CheckBox
        ViewbackButton             matlab.ui.control.Button
        ViewfrontButton            matlab.ui.control.Button
        ViewbottomButton           matlab.ui.control.Button
        ViewtopButton              matlab.ui.control.Button
        ViewrightButton            matlab.ui.control.Button
        ViewleftButton             matlab.ui.control.Button
        ShowbeamCheckBox           matlab.ui.control.CheckBox
        RVentralDCCheckBox         matlab.ui.control.CheckBox
        ShowheadmeshCheckBox       matlab.ui.control.CheckBox
        Label                      matlab.ui.control.Label
        RAccumbensCheckBox         matlab.ui.control.CheckBox
        RAmygdalaCheckBox          matlab.ui.control.CheckBox
        RHippocampCheckBox         matlab.ui.control.CheckBox
        RPallidumCheckBox          matlab.ui.control.CheckBox
        RPutamenCheckBox           matlab.ui.control.CheckBox
        RCaudateCheckBox           matlab.ui.control.CheckBox
        RThalamCheckBox            matlab.ui.control.CheckBox
        LVentralDCCheckBox         matlab.ui.control.CheckBox
        LAccumbensCheckBox         matlab.ui.control.CheckBox
        LAmygdalaCheckBox          matlab.ui.control.CheckBox
        LHippocampCheckBox         matlab.ui.control.CheckBox
        LPallidumCheckBox          matlab.ui.control.CheckBox
        LPutamenCheckBox           matlab.ui.control.CheckBox
        LCaudateCheckBox           matlab.ui.control.CheckBox
        LThalamCheckBox            matlab.ui.control.CheckBox
        SelectNucleiDropDown       matlab.ui.control.DropDown
        SelectprecalculationfolderButton  matlab.ui.control.Button
        DisplayresultsButton       matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
        UIAxes2                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)

        sol_folder;
        nucleus = 1;
        SOL_beams;        SOL_scalpmaps;        DATA;       MSOUND_inputs;   
        PLOT_scalpmap_h1;  PLOT_scalpmap_h2;       
        transd_pos;     best_pos;
        PLOT_transd;        PLOT_transd_cen;        PLOT_transd_nor;
        head_mesh;
        PLOT_headmesh;
        PLOT_beams;
        CC;   alphas;
        plot_nuc = zeros(16,1); 
        PLOT_nuc = cell(16,1);     
        xlabels_with_color = cell(16,1);
        USpos_h1;  USpos_h2;  
        ind_h1;  ind_h2;
        perim_h1;  perim_h2;  perim_all;
        PLOT_perim_h1;  PLOT_perim_h2;  PLOT_perim_all;
        stream_3d_data = 0;
        camid = 0;
        tcpip_object = [];
        transd_pos_cur;
        brainsight_filename;

    end
    
    methods (Access = private)

        function select_right_nuclei( app , value )
            app.plot_nuc(9:16) = value;
            app.RThalamCheckBox.Value = value;
            app.RCaudateCheckBox.Value = value;
            app.RPutamenCheckBox.Value = value;
            app.RPallidumCheckBox.Value = value;
            app.RHippocampCheckBox.Value = value;
            app.RAmygdalaCheckBox.Value = value;
            app.RAccumbensCheckBox.Value = value;
            app.RVentralDCCheckBox.Value = value;
            update_nuclei_vis( app );
        end

        function select_left_nuclei( app , value )
            app.plot_nuc(1:8) = value;
            app.LThalamCheckBox.Value = value;
            app.LCaudateCheckBox.Value = value;
            app.LPutamenCheckBox.Value = value;
            app.LPallidumCheckBox.Value = value;
            app.LHippocampCheckBox.Value = value;
            app.LAmygdalaCheckBox.Value = value;
            app.LAccumbensCheckBox.Value = value;
            app.LVentralDCCheckBox.Value = value;
            update_nuclei_vis( app );
        end

        function initialize_nuclei_checkBoxes( app )

            app.plot_nuc(:) = 0;
            app.plot_nuc( app.nucleus ) = 1;

            if app.nucleus == 1 
                app.LThalamCheckBox.Value = 1;
                app.LThalamCheckBox.FontColor = 'cyan';
            else
                app.LThalamCheckBox.Value = 0;
                app.LThalamCheckBox.FontColor = 'black';
            end

            if app.nucleus == 2
                app.LCaudateCheckBox.Value = 1;
                app.LCaudateCheckBox.FontColor = 'cyan';
            else
                app.LCaudateCheckBox.Value = 0;
                app.LCaudateCheckBox.FontColor = 'black';
            end

            if app.nucleus == 3
                app.LPutamenCheckBox.Value = 1;
                app.LPutamenCheckBox.FontColor = 'cyan';
            else
                app.LPutamenCheckBox.Value = 0;
                app.LPutamenCheckBox.FontColor = 'black';
            end

            if app.nucleus == 4
                app.LPallidumCheckBox.Value = 1;
                app.LPallidumCheckBox.FontColor = 'cyan';
            else
                app.LPallidumCheckBox.Value = 0;
                app.LPallidumCheckBox.FontColor = 'black';
            end

            if app.nucleus == 5
                app.LHippocampCheckBox.Value = 1;
                app.LHippocampCheckBox.FontColor = 'cyan';
            else
                app.LHippocampCheckBox.Value = 0;
                app.LHippocampCheckBox.FontColor = 'black';
            end

            if app.nucleus == 6
                app.LAmygdalaCheckBox.Value = 1;
                app.LAmygdalaCheckBox.FontColor = 'cyan';
            else
                app.LAmygdalaCheckBox.Value = 0;
                app.LAmygdalaCheckBox.FontColor = 'black';
            end

            if app.nucleus == 7
                app.LAccumbensCheckBox.Value = 1;
                app.LAccumbensCheckBox.FontColor = 'cyan';
            else
                app.LAccumbensCheckBox.Value = 0;
                app.LAccumbensCheckBox.FontColor = 'black';
            end

            if app.nucleus == 8
                app.LVentralDCCheckBox.Value = 1;
                app.LVentralDCCheckBox.FontColor = 'cyan';
            else
                app.LVentralDCCheckBox.Value = 0;
                app.LVentralDCCheckBox.FontColor = 'black';
            end
            
            if app.nucleus == 9
                app.RThalamCheckBox.Value = 1;
                app.RThalamCheckBox.FontColor = 'cyan';
            else
                app.RThalamCheckBox.Value = 0;
                app.RThalamCheckBox.FontColor = 'black';
            end

            if app.nucleus == 10
                app.RCaudateCheckBox.Value = 1;
                app.RCaudateCheckBox.FontColor = 'cyan';
            else
                app.RCaudateCheckBox.Value = 0;
                app.RCaudateCheckBox.FontColor = 'black';
            end

            if app.nucleus == 11
                app.RPutamenCheckBox.Value = 1;
                app.RPutamenCheckBox.FontColor = 'cyan';
            else
                app.RPutamenCheckBox.Value = 0;
                app.RPutamenCheckBox.FontColor = 'black';
            end

            if app.nucleus == 12
                app.RPallidumCheckBox.Value = 1;
                app.RPallidumCheckBox.FontColor = 'cyan';
            else
                app.RPallidumCheckBox.Value = 0;
                app.RPallidumCheckBox.FontColor = 'black';
            end

            if app.nucleus == 13
                app.RHippocampCheckBox.Value = 1;
                app.RHippocampCheckBox.FontColor = 'cyan';
            else
                app.RHippocampCheckBox.Value = 0;
                app.RHippocampCheckBox.FontColor = 'black';
            end

            if app.nucleus == 14
                app.RAmygdalaCheckBox.Value = 1;
                app.RAmygdalaCheckBox.FontColor = 'cyan';
            else
                app.RAmygdalaCheckBox.Value = 0;
                app.RAmygdalaCheckBox.FontColor = 'black';
            end

            if app.nucleus == 15
                app.RAccumbensCheckBox.Value = 1;
                app.RAccumbensCheckBox.FontColor = 'cyan';
            else
                app.RAccumbensCheckBox.Value = 0;
                app.RAccumbensCheckBox.FontColor = 'black';
            end

            if app.nucleus == 16
                app.RVentralDCCheckBox.Value = 1;
                app.RVentralDCCheckBox.FontColor = 'cyan';
            else
                app.RVentralDCCheckBox.Value = 0;
                app.RVentralDCCheckBox.FontColor = 'black';
            end

        end
   

        function update_message( app , str )
            app.Label.Text = str;
            app.Label.FontColor = 'red';
            pause(0.1);
        end

        function plot_nuclei( app )
            for ii = 1 : 16
                if ii == app.nucleus
                    color = 'cyan';
                else
                    color = [ 0.5 0.5 0.5 ];
                end
                app.PLOT_nuc{ii} = patch( app.UIAxes , app.SOL_scalpmaps.asegSurf{ii} ,'FaceColor' , color , 'EdgeColor' , 'none' , 'FaceAlpha' , 0.3 , 'displayname' , strrep(app.SOL_scalpmaps.alabels{ii},'_',' ') ); 
            end
            drawnow;
        end

        function update_nuclei_vis( app )
            for ii = 1 : 16
                if app.plot_nuc(ii) == 1
                    app.PLOT_nuc{ii}.Visible = 'on';
                else
                    app.PLOT_nuc{ii}.Visible = 'off';
                end
            end
        end


        function split_scalpmap( app )

            addpath( './Library' )

            % find center of vertices
            center = mean( app.DATA.USpos.vertices , 1 );
                       
            % define hemispheres
            h1 = find( app.DATA.USpos.vertices(:,2) >  center(2) );
            h2 = find( app.DATA.USpos.vertices(:,2) <= center(2) );

            tmp1 = app.DATA.USpos.faces(:,1);
            tmp2 = app.DATA.USpos.faces(:,2);
            tmp3 = app.DATA.USpos.faces(:,3);

            % extract h1
            app.ind_h1 = union( union( find(ismember(tmp1,h1)) , find(ismember(tmp2,h1))  ) , find(ismember(tmp3,h1))  );
            app.USpos_h1 = app.DATA.USpos;    app.USpos_h1.faces = app.USpos_h1.faces(app.ind_h1,:);

            % extract h1
            app.ind_h2 = union( union( find(ismember(tmp1,h2)) , find(ismember(tmp2,h2))  ) , find(ismember(tmp3,h2))  );
            app.USpos_h2 = app.DATA.USpos;    app.USpos_h2.faces = app.USpos_h2.faces(app.ind_h2,:);

            % perimeter, h1            
            [ BF  BE ] = find_boundary_faces( app.USpos_h1.faces ); 
            app.perim_h1 = perimeter_boundary_faces( BE );

            % perimeter, h2        
            [ BF  BE ] = find_boundary_faces( app.USpos_h2.faces ); 
            app.perim_h2 = perimeter_boundary_faces( BE );

            % perimeter, all       
            [ BF  BE ] = find_boundary_faces( app.DATA.USpos.faces ); 
            app.perim_all = perimeter_boundary_faces( BE );
            
        end


        function plot_scalp_map( app )

            pts = [];

            update_message( app , sprintf( 'Plotting scalp map for the %s...' , strrep( app.SOL_scalpmaps.alabels{app.nucleus} , '_' , ' ' ) ) );

            hold( app.UIAxes , 'on' );

            app.PLOT_scalpmap_h1 = patch( app.UIAxes , app.USpos_h1 ,'FaceColor' , 'interp' , 'FaceVertexCData' , app.SOL_scalpmaps.scalpMaps_verts(:,app.nucleus) , 'EdgeColor' , 'none' , 'FaceAlpha' , 1 , 'displayname' , [ 'Scalp map ' strrep(app.SOL_scalpmaps.alabels{app.nucleus},'_',' ') ', Left hemisphere'  ] , 'ButtonDownFcn' , @mouse_click ); 
            app.PLOT_scalpmap_h2 = patch( app.UIAxes , app.USpos_h2 ,'FaceColor' , 'interp' , 'FaceVertexCData' , app.SOL_scalpmaps.scalpMaps_verts(:,app.nucleus) , 'EdgeColor' , 'none' , 'FaceAlpha' , 1 , 'displayname' , [ 'Scalp map ' strrep(app.SOL_scalpmaps.alabels{app.nucleus},'_',' ') ', Right hemisphere'  ] , 'ButtonDownFcn' , @mouse_click ); 

            % h1 perimeter
            pts = [ app.USpos_h1.vertices(app.perim_h1{1}(:,1),:)   ;  app.USpos_h1.vertices(app.perim_h1{1}(:,2),:) ];
            app.PLOT_perim_h1 = plot3( app.UIAxes, pts(:,1), pts(:,2) , pts(:,3) , '-' , 'Color' , 'black' , 'LineWidth' , 1.5 , 'Visible' , 'off' , 'displayname' , 'Left hemi. perimeter' );
            
            % h2 perimeter
            pts = [ app.USpos_h2.vertices(app.perim_h2{1}(:,1),:)   ;  app.USpos_h2.vertices(app.perim_h2{1}(:,2),:) ];
            app.PLOT_perim_h2 = plot3( app.UIAxes, pts(:,1), pts(:,2) , pts(:,3) , '-' , 'Color' , 'black' , 'LineWidth' , 1.5 , 'Visible' , 'off', 'displayname' , 'Right hemi. perimeter'  );

            % all perimeter
            pts = [ app.DATA.USpos.vertices(app.perim_all{1}(:,1),:)   ;  app.DATA.USpos.vertices(app.perim_all{1}(:,2),:) ];
            app.PLOT_perim_all = plot3( app.UIAxes, pts(:,1), pts(:,2) , pts(:,3) , '-' , 'Color' , 'black' , 'LineWidth' , 1.5 , 'Visible' , 'on', 'displayname' , 'Scalp map perimeter'  );

            axis( app.UIAxes , 'image' , 'off' );
            set( app.UIAxes , 'Clipping' , 'off' );
            title( app.UIAxes , '' );
            caxis( app.UIAxes , [ 0  max(app.SOL_scalpmaps.scalpMaps_verts(:,app.nucleus))*0.95 ] );

            % this is the function that allows updating the display when
            % the user clicks on the scalp map, i.e. navigation
            function mouse_click( ~ , eventData )

                % declare variables to avoid 'static workplace' errors
                point = []; val = []; idx = [];

                % get transducer closest to mouse click
                point = eventData.IntersectionPoint;
                [ val  app.transd_pos ] = min( sum( abs(app.DATA.CENTER - point).^2 , 2 ) );

                % plot transducer
                plot_transducer( app );
    
                % plot beam
                plot_beam( app );
                update_beam_vis( app , app.ShowbeamCheckBox.Value );

                % delete annoying data tips if there are any
                delete(findall(app.UIAxes,'Type','hggroup'));
            
                % plot power
                plot_power_bar( app );
    
                update_message( app , sprintf('Current scalp map point = [ %3.1f  %3.1f  %3.1f ], transducer position #%d',point(1)*1000,point(2)*1000,point(3)*1000 , app.transd_pos ) );
            end
        end


        function  navigate_from_camera( app , pref )

            app.transd_pos_cur = -1;

            % stream 3D coordinates from Localite or Brainsight until the
            % operator presses the 'Stop' button
            while app.stream_3d_data == 1
         
                % *** LOCALITE ***
                if app.camid == 1

                    % read coil tracker information (#1) until we have a valid entry
                    data = read( app.tcpip_object );
                    coil_info = extractBetween(char(data),'"coil_0_position": {','}}'); 
                    while isempty(coil_info) == 1 
                        data = read( app.tcpip_object );
                        coil_info = extractBetween(char(data),'"coil_0_position": {','}}'); 
                        if isempty(coil_info) == 0
                            break;
                        end
                    end
                        
                    % extract position
                    q0   = str2double(extractBetween(coil_info(end),'"q0":',','));
                    qx   = str2double(extractBetween(coil_info(end),'"qx":',','));
                    qy   = str2double(extractBetween(coil_info(end),'"qy":',','));
                    qz   = str2double(extractBetween(coil_info(end),'"qz":',','));
                    MovX = str2double(extractBetween(coil_info(end),'"x":',','));
                    MovY = str2double(extractBetween(coil_info(end),'"y":',','));
                    MovZ = str2double(  extractAfter(coil_info(end),'"z":'));
                     
                    point = [ MovY  -MovX   MovZ ] + [ npos(2)  -npos(1)  npos(3) ];

                else  % *** BRAINSIGHT ***

                    [ status  line ] = system( [ 'tail -n 1 ' app.brainsight_filename ] );  % quick way to grab the last line of the file
                    if status > 0
                        mssg = [ 'Problem reading '  app.brainsight_filename ];
                        update_message( app , mssg );
                        error( mssg );
                    end

                    pause(0.01);  % wait 10 ms between updates
                    
                    if ~ischar(line)
                        continue;
                    end
                    
                    tokens = strsplit(line, '\t');
                    
                    % Check if the line is for the Polaris Tool: change this with the
                    % actual coil identifier when you connect to brainsight
                    if strcmp(tokens{1}, 'Polaris Tool')
                        x = str2num( tokens{7} );
                        y = str2num( tokens{8} );
                        z = str2num( tokens{9} );
                        point = [ x  y  z ] * 0.001;  % mm -> m  

                        % TEST!!!
                        % point = randn( 1 , 3 )  % mimick navigation
                       
                    end
                end
 
                % closest transducer to current position 
                [ val  app.transd_pos ] = min( sqrt( sum( abs(app.DATA.CENTER - point).^2 , 2 ) ) );

                if app.transd_pos ~= app.transd_pos_cur

                    app.transd_pos_cur = app.transd_pos;
                    update_message( app , sprintf('%s: Current scalp map point = [ %3.1f  %3.1f  %3.1f ], TD pos. #%d, D=%3.3fmm' , pref , point(1)*1000 , point(2)*1000 , point(3)*1000 , app.transd_pos , val ) );
    
                    % plot transducer
                    plot_transducer( app );
        
                    % plot beam
                    plot_beam( app );
                    update_beam_vis( app , app.ShowbeamCheckBox.Value );
    
                    % delete annoying data tips if there are any
                    delete(findall(app.UIAxes,'Type','hggroup'));
                
                    % plot power
                    plot_power_bar( app );

                end
            end

            if app.camid == 1  % Localite
                delete( app.tcpip_object );
            else               % Brainsight
                ;
            end

        end


        function update_scalpmap_vis( app )

            if app.ShowscalpmapleftCheckBox.Value == 1  &&  app.ShowscalpmaprightCheckBox.Value == 0
                app.PLOT_scalpmap_h1.Visible = 'on';
                app.PLOT_scalpmap_h2.Visible = 'off';

                app.PLOT_perim_h1.Visible = 'on';
                app.PLOT_perim_h2.Visible = 'off';
                app.PLOT_perim_all.Visible = 'off';

            elseif app.ShowscalpmapleftCheckBox.Value == 0  &&  app.ShowscalpmaprightCheckBox.Value == 1
                app.PLOT_scalpmap_h1.Visible = 'off';
                app.PLOT_scalpmap_h2.Visible = 'on';

                app.PLOT_perim_h1.Visible = 'off';
                app.PLOT_perim_h2.Visible = 'on';
                app.PLOT_perim_all.Visible = 'off';

            elseif app.ShowscalpmapleftCheckBox.Value == 1  &&  app.ShowscalpmaprightCheckBox.Value == 1
                app.PLOT_scalpmap_h1.Visible = 'on';
                app.PLOT_scalpmap_h2.Visible = 'on';

                app.PLOT_perim_h1.Visible = 'off';
                app.PLOT_perim_h2.Visible = 'off';
                app.PLOT_perim_all.Visible = 'on';

            elseif app.ShowscalpmapleftCheckBox.Value == 0  &&  app.ShowscalpmaprightCheckBox.Value == 0
                app.PLOT_scalpmap_h1.Visible = 'off';
                app.PLOT_scalpmap_h2.Visible = 'off';

                app.PLOT_perim_h1.Visible = 'off';
                app.PLOT_perim_h2.Visible = 'off';
                app.PLOT_perim_all.Visible = 'off';
            end
        end
    
        function plot_transducer( app )

            delete( app.PLOT_transd ); 
            delete( app.PLOT_transd_cen );
            delete( app.PLOT_transd_nor );

            theta = linspace( 0 , 2*pi , 100 )';
            cen = app.MSOUND_inputs.CENTER_mv(app.transd_pos , :);  % display transducer at correct distance from scalp
            nor = app.DATA.NOR( app.transd_pos , : );
            u1 = cross( nor , [1 0 0] );
            u2 = cross( u1 , nor );
            u1 = u1 / norm(u1);
            u2 = u2 / norm(u2);
            circ = cen  +  app.DATA.transd_params(2)/2000*cos(theta)*u1  +  app.DATA.transd_params(2)/2000*sin(theta)*u2;
            
            app.PLOT_transd        = plot3( app.UIAxes , circ(:,1) , circ(:,2) , circ(:,3) , '-k' , 'LineWidth' , 3 , 'displayname' , 'Transducer' ); 
            app.PLOT_transd_cen    = plot3( app.UIAxes , cen(1) , cen(2), cen(3) , 'ok' , 'MarkerFaceColor' , 'black' , 'MarkerSize', 5 , 'displayname' , 'Transd. center' );
            
            pt2 = cen - nor * 0.15;
            all = [ cen ; pt2 ];
            app.PLOT_transd_nor    = plot3( app.UIAxes , all(:,1) , all(:,2), all(:,3) , '--k' , 'LineWidth' , 1 , 'displayname' , 'Transd. centerline' );
            
        end


        function plot_head_mesh( app )
            app.PLOT_headmesh = patch( app.UIAxes , app.head_mesh ,'FaceColor' , 'none' , 'EdgeColor' , [ 0.4 0.4 0.4 ] , 'displayname' , 'Head mesh' ); 
        end


        function update_head_mesh_vis( app , value )
            if value == 1
                app.PLOT_headmesh.Visible = 'on';
            else
                app.PLOT_headmesh.Visible = 'off';
            end                
        end
        

        function plot_power_bar( app )
            power = app.SOL_scalpmaps.scalpMaps_faces( app.transd_pos , : );
            BAR = bar( app.UIAxes2 , [1:16] , power , 'FaceColor' , 'flat' );
            BAR.CData = ones(16,3) * 0.5;
            BAR.CData(app.nucleus,:) = [0 1 1];
            set( app.UIAxes2 , 'XTick' , [1:16] , 'FontSize' , 12 , 'XTickLabel' , app.xlabels_with_color  );
            xlabel( app.UIAxes2 , '' );
            ylabel( app.UIAxes2 , 'Intensity in nuclei [a.u.]' );
            title( app.UIAxes2 , '' );        
            axis( app.UIAxes2 , [ 0.5  16.5  0  max(power) ] );
        end


        function plot_beam( app )
            delete_beam(app);
            for ii = 1 : numel( app.SOL_beams.values )     
                app.PLOT_beams{ii} = patch( app.UIAxes , 'Faces' , app.SOL_beams.Faces{app.transd_pos,ii} , 'Vertices' , app.SOL_beams.Vertices{app.transd_pos,ii} , 'FaceColor' , interp1( linspace(0,1,size(app.CC,1)) , app.CC , app.SOL_beams.values(ii) ) , 'FaceAlpha' , app.alphas(ii) , 'EdgeColor' , 'none' , 'displayname' , sprintf('Beam %d%% isosurf.',int32(app.SOL_beams.values(ii)*100)) );
            end
        end


        function delete_beam( app )
            for ii = 1 : numel( app.SOL_beams.values )     
                delete( app.PLOT_beams{ii} );
            end
        end

        function update_beam_vis( app , value )
            for ii = 1 : numel( app.SOL_beams.values )     
                if value == 1
                    app.PLOT_beams{ii}.Visible = 'on';
                else
                    app.PLOT_beams{ii}.Visible = 'off';
                end
            end
        end  

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectprecalculationfolderButton
        function SelectprecalculationfolderButtonPushed(app, event)

            addpath( './Library/' );

            path = uigetdir( 'Data' , 'Select subject folder (not individual files)');  
            app.sol_folder = path;

            update_message( app , sprintf('Loading pre-calculation results in %s, please wait...',app.sol_folder) );
            app.SOL_beams       = load( [ app.sol_folder '/SOL_Beams.mat' ] );
            app.SOL_scalpmaps   = load( [ app.sol_folder '/SOL_ScalpMaps.mat' ] );
            app.DATA            = load( [ app.sol_folder '/app_data.mat' ] );
            app.MSOUND_inputs   = load( [ app.sol_folder '/mSOUND_inputs.mat' ] );

            app.head_mesh = app.DATA.skinS;
            % ind = unique( find( app.head_mesh.vertices(:,1) <= (app.head_mesh.vertices(:,3)-app.DATA.ROI_isc) / app.DATA.ROI_slope) );
            % app.head_mesh.faces = remove_faces_surf_mesh( app.head_mesh.faces , ind );

            app.PLOT_beams = cell( numel(app.SOL_beams.values) , 1 );

            C1 = [ linspace( 0.04 , 1 , 25 )'  ;  ones( 64-25,1 ) ];
            C2 = [ zeros( 25 , 1 ) ; linspace( 0 , 1 , 25 )'  ;  ones( 64-2*25,1 ) ];
            C3 = [ zeros( 2*25 , 1 ) ; linspace( 0 , 1 , 64-2*25 )' ];
            app.CC = [ C1 C2 C3 ];
            app.alphas = app.SOL_beams.values;

            update_message( app , 'Done!' );
        end

        % Value changed function: SelectNucleiDropDown
        function SelectNucleiDropDownValueChanged(app, event)
            Nuclei = app.SelectNucleiDropDown.Value;
            switch Nuclei
                case 'Left Thalamus'
                    app.nucleus = 1;
                case 'Left Caudate'
                    app.nucleus = 2;
                case 'Left Putamen'
                    app.nucleus = 3;
                case 'Left Pallidum'
                    app.nucleus = 4;
                case 'Left Hippocampus'
                    app.nucleus = 5;
                case 'Left Amygdala'
                    app.nucleus = 6;
                case 'Left Accumbens area'
                    app.nucleus = 7;
                case 'Left VentralDC'
                    app.nucleus = 8;
                case 'Right Thalamus'
                    app.nucleus = 9;
                case 'Right Caudate'
                    app.nucleus = 10;
                case 'Right Putamen'
                    app.nucleus = 11;
                case 'Right Pallidum'
                    app.nucleus = 12;
                case 'Right Hippocampus'
                    app.nucleus = 13;
                case 'Right Amygdala'
                    app.nucleus = 14;
                case 'Right Accumbens area'
                    app.nucleus = 15;
                case 'Right VentralDC'
                    app.nucleus = 16;
                    
            end
        end

        % Button pushed function: DisplayresultsButton
        function DisplayresultsButtonPushed(app, event)

            cla( app.UIAxes );

            % plot nuclei
            plot_nuclei( app );
            initialize_nuclei_checkBoxes( app );
            update_nuclei_vis(app); 
            
            % plot scalp map
            split_scalpmap( app );
            plot_scalp_map( app );
            view( app.UIAxes , 30 , 0 );
            app.ShowscalpmapleftCheckBox.Value = 1;
            app.ShowscalpmaprightCheckBox.Value = 1;

            %  plot head mesh
            plot_head_mesh( app );
            app.ShowheadmeshCheckBox.Value = 0;
            update_head_mesh_vis( app , 0 );

            % plot transducer
            [ V   app.transd_pos ] = max( app.SOL_scalpmaps.scalpMaps_faces(:,app.nucleus) );
            app.best_pos = app.transd_pos;
            plot_transducer( app );

            % plot beam
            plot_beam( app );
            app.ShowbeamCheckBox.Value = 1;

            % plot power
            for ii = 1 : 16
                if ii == app.nucleus
                    app.xlabels_with_color{ii} = [ '\color{cyan} '  strrep( app.SOL_scalpmaps.alabels{ii} , '_' , ' ' ) ];
                else
                    app.xlabels_with_color{ii} = [ '\color{black} '  strrep( app.SOL_scalpmaps.alabels{ii} , '_' , ' ' ) ];
                end
            end
            plot_power_bar( app );

            update_message( app , 'Done!' );

        end

        % Value changed function: LThalamCheckBox
        function LThalamCheckBoxValueChanged2(app, event)
            app.plot_nuc(1) = app.LThalamCheckBox.Value;
            update_nuclei_vis( app );
        end

        % Value changed function: LCaudateCheckBox
        function LCaudateCheckBoxValueChanged(app, event)
            app.plot_nuc(2) = app.LCaudateCheckBox.Value;
            update_nuclei_vis( app );
        end

        % Value changed function: LPutamenCheckBox
        function LPutamenCheckBoxValueChanged(app, event)
            app.plot_nuc(3) = app.LPutamenCheckBox.Value;
            update_nuclei_vis( app );
        end

        % Value changed function: LPallidumCheckBox
        function LPallidumCheckBoxValueChanged(app, event)
            app.plot_nuc(4) = app.LPallidumCheckBox.Value;
            update_nuclei_vis( app );
        end

        % Value changed function: LHippocampCheckBox
        function LHippocampCheckBoxValueChanged(app, event)
            app.plot_nuc(5) = app.LHippocampCheckBox.Value;
            update_nuclei_vis( app );
        end

        % Value changed function: LAmygdalaCheckBox
        function LAmygdalaCheckBoxValueChanged(app, event)
            app.plot_nuc(6) = app.LAmygdalaCheckBox.Value;
            update_nuclei_vis( app );
        end

        % Value changed function: LAccumbensCheckBox
        function LAccumbensCheckBoxValueChanged(app, event)
            app.plot_nuc(7) = app.LAccumbensCheckBox.Value;
            update_nuclei_vis( app );
        end

        % Value changed function: LVentralDCCheckBox
        function LVentralDCCheckBoxValueChanged(app, event)
            app.plot_nuc(8) = app.LVentralDCCheckBox.Value;
            update_nuclei_vis( app );
        end

        % Value changed function: RThalamCheckBox
        function RThalamCheckBoxValueChanged(app, event)
            app.plot_nuc(9) = app.RThalamCheckBox.Value;
            update_nuclei_vis( app );
        end

        % Value changed function: RCaudateCheckBox
        function RCaudateCheckBoxValueChanged(app, event)
            app.plot_nuc(10) = app.RCaudateCheckBox.Value;
            update_nuclei_vis( app );
        end

        % Value changed function: RPutamenCheckBox
        function RPutamenCheckBoxValueChanged(app, event)
            app.plot_nuc(11) = app.RPutamenCheckBox.Value;
            update_nuclei_vis( app );
        end

        % Value changed function: RPallidumCheckBox
        function RPallidumCheckBoxValueChanged(app, event)
            app.plot_nuc(12) = app.RPallidumCheckBox.Value;
            update_nuclei_vis( app );
        end

        % Value changed function: RHippocampCheckBox
        function RHippocampCheckBoxValueChanged(app, event)
            app.plot_nuc(13) = app.RHippocampCheckBox.Value;
            update_nuclei_vis( app );
        end

        % Value changed function: RAmygdalaCheckBox
        function RAmygdalaCheckBoxValueChanged(app, event)
            app.plot_nuc(14) = app.RAmygdalaCheckBox.Value;
            update_nuclei_vis( app );
        end

        % Value changed function: RAccumbensCheckBox
        function RAccumbensCheckBoxValueChanged(app, event)
            app.plot_nuc(15) = app.RAccumbensCheckBox.Value;
            update_nuclei_vis( app );
        end

        % Value changed function: RVentralDCCheckBox
        function RVentralDCCheckBoxValueChanged(app, event)
            app.plot_nuc(16) = app.RVentralDCCheckBox.Value;
            update_nuclei_vis( app );
        end

        % Value changed function: ToggleallleftCheckBox
        function ToggleallleftCheckBoxValueChanged(app, event)
            value = app.ToggleallleftCheckBox.Value;
            select_left_nuclei( app , value );
        end

        % Value changed function: ToggleallrightCheckBox
        function ToggleallrightCheckBoxValueChanged2(app, event)
            value = app.ToggleallrightCheckBox.Value;
            select_right_nuclei( app , value );
        end

        % Value changed function: ShowheadmeshCheckBox
        function ShowheadmeshCheckBoxValueChanged(app, event)
            value = app.ShowheadmeshCheckBox.Value;
            update_head_mesh_vis( app , value );
        end

        % Value changed function: ShowbeamCheckBox
        function ShowbeamCheckBoxValueChanged(app, event)
            value = app.ShowbeamCheckBox.Value;
            update_beam_vis( app , value );
        end

        % Value changed function: ShowscalpmapleftCheckBox
        function ShowscalpmapleftCheckBoxValueChanged(app, event)
            update_scalpmap_vis( app );
        end

        % Value changed function: ShowscalpmaprightCheckBox
        function ShowscalpmaprightCheckBoxValueChanged(app, event)
            update_scalpmap_vis( app );
        end

        % Button pushed function: ViewrightButton
        function ViewrightButtonPushed(app, event)
            view( app.UIAxes , 0 , 0 );
        end

        % Button pushed function: ViewleftButton
        function ViewleftButtonPushed(app, event)
            view( app.UIAxes , 180 , 0 );
        end

        % Button pushed function: ViewtopButton
        function ViewtopButtonPushed(app, event)
            view( app.UIAxes , 270 , 90 );
        end

        % Button pushed function: ViewbottomButton
        function ViewbottomButtonPushed(app, event)
            view( app.UIAxes , -90 , -90 );
        end

        % Button pushed function: ViewfrontButton
        function ViewfrontButtonPushed(app, event)
            view( app.UIAxes , 90 , 0 );
        end

        % Button pushed function: ViewbackButton
        function ViewbackButtonPushed(app, event)
            view( app.UIAxes , -90 , 0 );
        end

        % Button pushed function: TimetricleftButton
        function TimetricleftButtonPushed(app, event)
            view( app.UIAxes , 140 , 10 );
        end

        % Button pushed function: TimetricrightButton
        function TimetricrightButtonPushed(app, event)
            view( app.UIAxes , 50 , 10 );
        end

        % Button pushed function: SavefiguresButton
        function SavefiguresButtonPushed(app, event)

            file = [ app.sol_folder '/FIG_'  strrep(app.SOL_scalpmaps.alabels{app.nucleus},'_','') '_transd' num2str(app.transd_pos) '.mat' ];
            update_message( app , [ 'Saving figures to ' file '...' ] );

            FIG_beam = figure(); figAxes = axes(FIG_beam);
            allChildren = app.UIAxes.XAxis.Parent.Children;
            copyobj(allChildren, figAxes);  axis image off;  view(30,0);
            delete(findall(FIG_beam,'Type','hggroup'));

            FIG_power = figure(); figAxes2 = axes(FIG_power);
            allChildren = app.UIAxes2.XAxis.Parent.Children;
            copyobj(allChildren, figAxes2);  
            FIG_power.CurrentAxes.YLabel.String = app.UIAxes2.YLabel.String;
            FIG_power.CurrentAxes.XTick = [1:16];
            FIG_power.CurrentAxes.XTickLabel = app.xlabels_with_color;

            save( file , 'FIG_beam' , 'FIG_power' );
            close(FIG_beam);
            close(FIG_power);

            update_message( app , [ 'Saving figures to ' file '... Done!' ] );
       
        end

        % Button pushed function: GotobestpositionButton
        function GotobestpositionButtonPushed(app, event)
                          
            app.transd_pos = app.best_pos;

            % plot transducer
            plot_transducer( app );

            % plot beam
            plot_beam( app );
            update_beam_vis( app , app.ShowbeamCheckBox.Value );

            % delete annoying data tips if there are any
            delete(findall(app.UIAxes,'Type','hggroup'));
        
            % plot power
            plot_power_bar( app );

            update_message( app , sprintf('Best transducer position #%d', app.transd_pos ) );

        end

        % Button pushed function: LocaliteButton
        function LocaliteButtonPushed(app, event)

            app.camid = 1;  % 1: Localite, 2: Brainsight
            app.stream_3d_data = 1;
            
            % create TCP/IP connection
            tcpip_info = inputdlg( { 'Enter IP address of Localite system:' , 'Enter transmitting JSON port #:' } );
            update_message( app , [ 'Attempting to open TCP/IP connection to' tcpip_info{1} ':'  tcpip_info{2} ', please wait...'  ] );

            app.tcpip_object = tcpclient( tcpip_info{1} , str2num(tcpip_info{2}) , 'Timeout' , 10 , 'ConnectTimeout' , 20 ); 

            if exist( 'tcpip_object' , 'var' ) == 0
                update_message( app , [ 'Could not open TCPIP ' tcpip_info{1} ':'  tcpip_info{2} '!' ] );

            else
                pref = 'Streaming Localite coordinates';
                update_message( app , pref );
                navigate_from_camera( app , pref );
            end

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stream_3d_data = 0;
        end

        % Button pushed function: BrainsightButton
        function BrainsightButtonPushed(app, event)

            app.camid = 2;  % 1: Localite, 2: Brainsight
            app.stream_3d_data = 1;

            update_message( app , 'Please select Brainsight streaming text file' );
            [ fname  dname ] = uigetfile( '*.txt' , 'Select Brainsight streaming file' );
            app.brainsight_filename = [ dname  fname ];
  
            pref = 'Streaming Brainsight coordinates';
            update_message( app , pref );
            navigate_from_camera( app , pref );

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DisplayresultsButton = uibutton(app.UIFigure, 'push');
            app.DisplayresultsButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayresultsButtonPushed, true);
            app.DisplayresultsButton.Text = 'Display results';
            app.SelectprecalculationfolderButton = uibutton(app.UIFigure, 'push');
            app.SelectprecalculationfolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectprecalculationfolderButtonPushed, true);
            app.SelectprecalculationfolderButton.Text = 'Select pre-calculation folder';
            app.SelectNucleiDropDown = uidropdown(app.UIFigure);
            app.SelectNucleiDropDown.Items = {'Left Thalamus', 'Left Caudate', 'Left Putamen', 'Left Pallidum', 'Left Hippocampus', 'Left Amygdala', 'Left Accumbens area', 'Left VentralDC', 'Right Thalamus', 'Right Caudate', 'Right Putamen', 'Right Pallidum', 'Right Hippocampus', 'Right Amygdala', 'Right Accumbens area', 'Right VentralDC'};
            app.SelectNucleiDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectNucleiDropDownValueChanged, true);
            app.SelectNucleiDropDown.Value = 'Left Thalamus';
            app.LThalamCheckBox = uicheckbox(app.UIFigure);
            app.LThalamCheckBox.ValueChangedFcn = createCallbackFcn(app, @LThalamCheckBoxValueChanged2, true);
            app.LThalamCheckBox.Text = 'L Thalam.';
            app.LCaudateCheckBox = uicheckbox(app.UIFigure);
            app.LCaudateCheckBox.ValueChangedFcn = createCallbackFcn(app, @LCaudateCheckBoxValueChanged, true);
            app.LCaudateCheckBox.Text = 'L Caudate';
            app.LPutamenCheckBox = uicheckbox(app.UIFigure);
            app.LPutamenCheckBox.ValueChangedFcn = createCallbackFcn(app, @LPutamenCheckBoxValueChanged, true);
            app.LPutamenCheckBox.Text = 'L Putamen';
            app.LPallidumCheckBox = uicheckbox(app.UIFigure);
            app.LPallidumCheckBox.ValueChangedFcn = createCallbackFcn(app, @LPallidumCheckBoxValueChanged, true);
            app.LPallidumCheckBox.Text = 'L Pallidum';
            app.LHippocampCheckBox = uicheckbox(app.UIFigure);
            app.LHippocampCheckBox.ValueChangedFcn = createCallbackFcn(app, @LHippocampCheckBoxValueChanged, true);
            app.LHippocampCheckBox.Text = 'L Hippocamp.';
            app.LAmygdalaCheckBox = uicheckbox(app.UIFigure);
            app.LAmygdalaCheckBox.ValueChangedFcn = createCallbackFcn(app, @LAmygdalaCheckBoxValueChanged, true);
            app.LAmygdalaCheckBox.Text = 'L Amygdala';
            app.LAccumbensCheckBox = uicheckbox(app.UIFigure);
            app.LAccumbensCheckBox.ValueChangedFcn = createCallbackFcn(app, @LAccumbensCheckBoxValueChanged, true);
            app.LAccumbensCheckBox.Text = 'L Accumbens ';
            app.LVentralDCCheckBox = uicheckbox(app.UIFigure);
            app.LVentralDCCheckBox.ValueChangedFcn = createCallbackFcn(app, @LVentralDCCheckBoxValueChanged, true);
            app.LVentralDCCheckBox.Text = 'L Ventral DC';
            app.RThalamCheckBox = uicheckbox(app.UIFigure);
            app.RThalamCheckBox.ValueChangedFcn = createCallbackFcn(app, @RThalamCheckBoxValueChanged, true);
            app.RThalamCheckBox.Text = 'R Thalam.';
            app.RCaudateCheckBox = uicheckbox(app.UIFigure);
            app.RCaudateCheckBox.ValueChangedFcn = createCallbackFcn(app, @RCaudateCheckBoxValueChanged, true);
            app.RCaudateCheckBox.Text = 'R Caudate';
            app.RPutamenCheckBox = uicheckbox(app.UIFigure);
            app.RPutamenCheckBox.ValueChangedFcn = createCallbackFcn(app, @RPutamenCheckBoxValueChanged, true);
            app.RPutamenCheckBox.Text = 'R Putamen';
            app.RPallidumCheckBox = uicheckbox(app.UIFigure);
            app.RPallidumCheckBox.ValueChangedFcn = createCallbackFcn(app, @RPallidumCheckBoxValueChanged, true);
            app.RPallidumCheckBox.Text = 'R Pallidum';
            app.RHippocampCheckBox = uicheckbox(app.UIFigure);
            app.RHippocampCheckBox.ValueChangedFcn = createCallbackFcn(app, @RHippocampCheckBoxValueChanged, true);
            app.RHippocampCheckBox.Text = 'R Hippocamp.';
            app.RAmygdalaCheckBox = uicheckbox(app.UIFigure);
            app.RAmygdalaCheckBox.ValueChangedFcn = createCallbackFcn(app, @RAmygdalaCheckBoxValueChanged, true);
            app.RAmygdalaCheckBox.Text = 'R Amygdala';
            app.RAccumbensCheckBox = uicheckbox(app.UIFigure);
            app.RAccumbensCheckBox.ValueChangedFcn = createCallbackFcn(app, @RAccumbensCheckBoxValueChanged, true);
            app.RAccumbensCheckBox.Text = 'R Accumbens ';
            app.Label = uilabel(app.UIFigure);
            app.ShowheadmeshCheckBox = uicheckbox(app.UIFigure);
            app.ShowheadmeshCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowheadmeshCheckBoxValueChanged, true);
            app.ShowheadmeshCheckBox.Text = 'Show head mesh';
            app.RVentralDCCheckBox = uicheckbox(app.UIFigure);
            app.RVentralDCCheckBox.ValueChangedFcn = createCallbackFcn(app, @RVentralDCCheckBoxValueChanged, true);
            app.RVentralDCCheckBox.Text = 'R Ventral DC';
            app.ShowbeamCheckBox = uicheckbox(app.UIFigure);
            app.ShowbeamCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowbeamCheckBoxValueChanged, true);
            app.ShowbeamCheckBox.Text = 'Show beam';
            app.ShowbeamCheckBox.Value = true;
            app.ViewleftButton = uibutton(app.UIFigure, 'push');
            app.ViewleftButton.ButtonPushedFcn = createCallbackFcn(app, @ViewleftButtonPushed, true);
            app.ViewleftButton.Text = 'View left';
            app.ViewrightButton = uibutton(app.UIFigure, 'push');
            app.ViewrightButton.ButtonPushedFcn = createCallbackFcn(app, @ViewrightButtonPushed, true);
            app.ViewrightButton.Text = 'View right';
            app.ViewtopButton = uibutton(app.UIFigure, 'push');
            app.ViewtopButton.ButtonPushedFcn = createCallbackFcn(app, @ViewtopButtonPushed, true);
            app.ViewtopButton.Text = 'View top';
            app.ViewbottomButton = uibutton(app.UIFigure, 'push');
            app.ViewbottomButton.ButtonPushedFcn = createCallbackFcn(app, @ViewbottomButtonPushed, true);
            app.ViewbottomButton.Text = 'View bottom';
            app.ViewfrontButton = uibutton(app.UIFigure, 'push');
            app.ViewfrontButton.ButtonPushedFcn = createCallbackFcn(app, @ViewfrontButtonPushed, true);
            app.ViewfrontButton.Text = 'View front';
            app.ViewbackButton = uibutton(app.UIFigure, 'push');
            app.ViewbackButton.ButtonPushedFcn = createCallbackFcn(app, @ViewbackButtonPushed, true);
            app.ViewbackButton.Text = 'View back';
            app.ToggleallleftCheckBox = uicheckbox(app.UIFigure);
            app.ToggleallleftCheckBox.ValueChangedFcn = createCallbackFcn(app, @ToggleallleftCheckBoxValueChanged, true);
            app.ToggleallleftCheckBox.Text = 'Toggle all left';
            app.SavefiguresButton = uibutton(app.UIFigure, 'push');
            app.SavefiguresButton.ButtonPushedFcn = createCallbackFcn(app, @SavefiguresButtonPushed, true);
            app.SavefiguresButton.Text = 'Save figures';
            app.TimetricleftButton = uibutton(app.UIFigure, 'push');
            app.TimetricleftButton.ButtonPushedFcn = createCallbackFcn(app, @TimetricleftButtonPushed, true);
            app.TimetricleftButton.Text = 'Timetric left';
            app.TimetricrightButton = uibutton(app.UIFigure, 'push');
            app.TimetricrightButton.ButtonPushedFcn = createCallbackFcn(app, @TimetricrightButtonPushed, true);
            app.TimetricrightButton.Text = 'Timetric right';
            app.ToggleallrightCheckBox = uicheckbox(app.UIFigure);
            app.ToggleallrightCheckBox.ValueChangedFcn = createCallbackFcn(app, @ToggleallrightCheckBoxValueChanged2, true);
            app.ToggleallrightCheckBox.Text = 'Toggle all right';
            app.ShowscalpmapleftCheckBox = uicheckbox(app.UIFigure);
            app.ShowscalpmapleftCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowscalpmapleftCheckBoxValueChanged, true);
            app.ShowscalpmapleftCheckBox.Text = 'Show scalp map (left)';
            app.ShowscalpmapleftCheckBox.Value = true;
            app.ShowscalpmaprightCheckBox = uicheckbox(app.UIFigure);
            app.ShowscalpmaprightCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowscalpmaprightCheckBoxValueChanged, true);
            app.ShowscalpmaprightCheckBox.Text = 'Show scalp map (right)';
            app.ShowscalpmaprightCheckBox.Value = true;
            app.GotobestpositionButton = uibutton(app.UIFigure, 'push');
            app.GotobestpositionButton.ButtonPushedFcn = createCallbackFcn(app, @GotobestpositionButtonPushed, true);
            app.GotobestpositionButton.Text = 'Go to best position';
            app.LOADSAVEDATALabel = uilabel(app.UIFigure);
            app.LOADSAVEDATALabel.Text = 'L O A D / S A V E   D A T A ';
            app.NAVIGATELabel = uilabel(app.UIFigure);
            app.NAVIGATELabel.Text = 'N A V I G A T E';
            app.NUCLEIVISIBILITYLabel = uilabel(app.UIFigure);
            app.NUCLEIVISIBILITYLabel.Text = 'N U C L E I   V I S I B I L I T Y';
            app.Step1Label = uilabel(app.UIFigure);
            app.Step1Label.Text = 'Step 1)';
            app.Step2Label = uilabel(app.UIFigure);
            app.Step2Label.Text = 'Step 2)';
            app.Step3Label = uilabel(app.UIFigure);
            app.Step3Label.Text = 'Step 3)';
            app.PointandclickonthescalpmaptoupdatetransducerLabel = uilabel(app.UIFigure);
            app.PointandclickonthescalpmaptoupdatetransducerLabel.Text = 'Point and click on the scalp map to update transducer ';
            app.ManualLabel = uilabel(app.UIFigure);
            app.ManualLabel.Text = 'Manual:';
            app.OpticalcameraLabel = uilabel(app.UIFigure);
            app.OpticalcameraLabel.Text = 'Optical camera:';
            app.LocaliteButton = uibutton(app.UIFigure, 'push');
            app.LocaliteButton.ButtonPushedFcn = createCallbackFcn(app, @LocaliteButtonPushed, true);
            app.LocaliteButton.Text = 'Localite';
            app.BrainsightButton = uibutton(app.UIFigure, 'push');
            app.BrainsightButton.ButtonPushedFcn = createCallbackFcn(app, @BrainsightButtonPushed, true);
            app.BrainsightButton.Text = 'Brainsight';
            app.DISPLAYLabel = uilabel(app.UIFigure);
            app.DISPLAYLabel.Text = 'D I S P L A Y';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = nav_GUI_MD

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end