classdef precalc_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GenerateMeshPanel              matlab.ui.container.Panel
        EdgelengthmmEditField          matlab.ui.control.EditField
        EdgelengthmmEditFieldLabel     matlab.ui.control.Label
        Step2RemovefacesbeloweyesearsLabel  matlab.ui.control.Label
        Step3MakesurenormalspointawayfromheadLabel  matlab.ui.control.Label
        Step1GeneratescalpmeshLabel    matlab.ui.control.Label
        InfoLabel                      matlab.ui.control.Label
        InvertNormalsCheckBox          matlab.ui.control.CheckBox
        ShowNormalsCheckBox            matlab.ui.control.CheckBox
        Label_4                        matlab.ui.control.Label
        RemoveinvalidfacesButton       matlab.ui.control.Button
        GenerateHeadMeshButton         matlab.ui.control.Button
        UIAxes_2                       matlab.ui.control.UIAxes
        EditField                      matlab.ui.control.EditField
        RunAcousticIntensityCalculationsPanel  matlab.ui.container.Panel
        SimulationfoldernamefullpathpreferredEditField  matlab.ui.control.EditField
        SimulationfoldernamefullpathpreferredEditFieldLabel  matlab.ui.control.Label
        NumberofpararellprocessingunitsEditField  matlab.ui.control.NumericEditField
        NumberofpararellprocessingunitsEditFieldLabel  matlab.ui.control.Label
        RunButton                      matlab.ui.control.Button
        Label_2                        matlab.ui.control.Label
        ChooseTransducerModelPanel     matlab.ui.container.Panel
        FrequencyKHzEditField          matlab.ui.control.NumericEditField
        FrequencyKHzLabel              matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        ThicknessmEditField            matlab.ui.control.NumericEditField
        ThicknessmEditFieldLabel       matlab.ui.control.Label
        ApartureRadiusmEditField       matlab.ui.control.NumericEditField
        ApartureRadiusmEditFieldLabel  matlab.ui.control.Label
        FocalDistancemEditField        matlab.ui.control.NumericEditField
        FocalDistancemEditFieldLabel   matlab.ui.control.Label
        ChooseTransducerModelDropDown  matlab.ui.control.DropDown
        DefineTransducerParametersLabel  matlab.ui.control.Label
        orLabel                        matlab.ui.control.Label
        LoadDataPanel                  matlab.ui.container.Panel
        Lamp_3                         matlab.ui.control.Lamp
        Lamp_2                         matlab.ui.control.Lamp
        Lamp                           matlab.ui.control.Lamp
        BrowseASEGButton               matlab.ui.control.Button
        DisplayImageButtonGroup        matlab.ui.container.ButtonGroup
        AsegButton                     matlab.ui.control.RadioButton
        PorosityButton                 matlab.ui.control.RadioButton
        HeadmaskButton                 matlab.ui.control.RadioButton
        BrowseporosityButton           matlab.ui.control.Button
        Slider                         matlab.ui.control.Slider
        Label_3                        matlab.ui.control.Label
        BrowseheadmaskButton           matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end


    properties (Access = private)
        mesh_ROI; 
        skinS; Xg; Yg; Zg;
        USpos = []; hmask; poro; aseg; selectedButton;    
        showNOR;
        normals_plot = gobjects(1);
        mesh_plot;
        CENTER;  NOR;
        PLINE_info;
        default_file_path = 'Data/';
        edge_length = 8;
        transd_params = [0 0 0 0];
        ppool = 10;
        output_folder = 'Simulation_test_001';

    end

    properties (Access = public)
        tkns=0; Freq=0; Foc=0; aptr=0;
        slidVal=128;
        ainds = [10 11 12 13 17 18 26 28 49 50 51 52 53 54 58 60];
        alabels = { 'Left_Thalamus' , ...
            'Left_Caudate' , ...
            'Left_Putamen' , ...
            'Left_Pallidum' , ...
            'Left_Hippocampus' , ...
            'Left_Amygdala' , ...
            'Left_Accumbens_area' , ...
            'Left_VentralDC' , ...
            'Right_Thalamus' , ...
            'Right_Caudate' , ...
            'Right_Putamen' , ...
            'Right_Pallidum' , ...
            'Right_Hippocampus' , ...
            'Right_Amygdala' , ...
            'Right_Accumbens_area' , ...
            'Right_VentralDC' };
        colors = 'rbcmkgrbcmkgrbcmkgrbcmkgrbcmkgrbcmkgrbcmkgrbcmkgrbcmkgrbcmkgrbcmkg';
    end

    methods (Access = public)

        function update_message( app , str )
            app.EditField.Value = str;
            app.EditField.FontColor = 'r';
        end

        function update_info( app , str )
            app.InfoLabel.Text = str;
        end

        function update_title( app , str )
            title(app.UIAxes_2,str);
        end

        function source_p = compute_source_AN( app , mgrid , TR_focus , TR_radius , c_brain , FREQ  )
            X = ones(mgrid.num_y,1) * mgrid.x;
            Y = ( ones(mgrid.num_x,1) * mgrid.y ).';
            delay = sqrt( X.^2 + Y.^2 + TR_focus^2 ) / c_brain;   % calculate the delay for each element [m]   
            delay = delay - min(min(delay));
            source_p = 1 * exp( 1i * 2 * pi * FREQ * delay );
            RHO = sqrt( X.^2 + Y.^2 );
            source_p( RHO > TR_radius ) = 0;
        end

        function source_p = compute_source_RS( app , mgrid , TR_focus , TR_radius , c_brain , rho_brain , FREQ  )
            DELTA = TR_focus - sqrt( TR_focus^2 - TR_radius^2 );  
            TD_mask = make_curved_transducer( mgrid.x , mgrid.y , mgrid.z , [ 0 0 0 ] , [0 0 1] , TR_focus , TR_radius*2 );  
             
            indTRANS = find( TD_mask );
            [ xxf   yyf   zzf ] = ndgrid( mgrid.x , mgrid.y , mgrid.z );
            [ xxf2  yyf2 ]    = ndgrid( mgrid.x , mgrid.y );  
            zzf2 = ones( size(xxf2) ) * DELTA;  % 2D input plane 
            source_p = rayleighSommerfeldIntegral( [ xxf(indTRANS)  yyf(indTRANS)  zzf(indTRANS) ] , ones(numel(indTRANS),1) , xxf2(:) , yyf2(:) , zzf2(:) , c_brain , rho_brain, FREQ );
            source_p = reshape( source_p , size(xxf2) ) / max(abs(source_p(:)));
        end

        function remove_faces_belowLine(app)
                        
            vx = app.USpos.vertices(:,1);
            vy = app.USpos.vertices(:,3);
            nsegs = length( app.PLINE_info ) - 1;  % number of segments of the polyline
            for ii = 1 : nsegs
                P1 = app.PLINE_info(ii   , :);
                P2 = app.PLINE_info(ii+1 , :);
                vylin = (vx-P1(1)) / (P2(1)-P1(1)) * (P2(2)-P1(2))  +   P1(2); 
                ind = find( vx>=(min(P1(1),P2(1)))  &  vx<=(max(P1(1),P2(1)))  &  vy<vylin  );
                app.USpos.faces = remove_faces_surf_mesh( app.USpos.faces , ind );
            end 

            % only keep the largest surface
            tmp = finddisconnsurf( app.USpos.faces );
            nmax = -1;  iimax = -1;
            for ii = 1 : numel(tmp)
                if size(tmp{ii},1) > nmax
                    nmax = size(tmp{ii},1);
                    iimax = ii;
                end
            end
            app.USpos.faces = tmp{iimax};

            % simple line
            % ind = find( app.USpos.vertices(:,1) > (app.USpos.vertices(:,3)-app.ROI_isc)/app.ROI_slope);
            % app.USpos.faces = remove_faces_surf_mesh( app.USpos.faces , ind );
        end

        function update_normals(app)
            P = app.USpos.vertices;
            t = app.USpos.faces;
            app.CENTER      = 1/3*(P(t(:, 1), :) + P(t(:, 2), :) + P(t(:, 3), :)); 
            app.NOR         = -surfacenorm(P,t); 

            if isvalid(app.normals_plot)
                removeNormals(app);
                showNormals(app);
            end
        end

        function plot_head_mesh(app)
            delete(app.mesh_plot);

            app.mesh_plot = patch(app.UIAxes_2,'faces',app.USpos.faces,'vertices',app.USpos.vertices,'FaceColor',[0.9 1 0.99],'FaceAlpha',0.9);
            camlight(app.UIAxes_2);
            hold(app.UIAxes_2, 'on');
            axis(app.UIAxes_2,'image');
            view(app.UIAxes_2,[30 0]);
            set(app.UIAxes_2,'Clipping','off');
        end

        function showNormals(app)    
            app.normals_plot = quiver3(app.UIAxes_2,app.CENTER(:,1),app.CENTER(:,2),app.CENTER(:,3),app.NOR(:,1),app.NOR(:,2),app.NOR(:,3),'r');
        end

        function removeNormals(app)
            delete( app.normals_plot );
        end

        function update_plot(app,imdata)
            imagesc(app.UIAxes,squeeze(imdata(floor(app.slidVal)+1,:,end:-1:1))');colormap(app.UIAxes,'gray');axis(app.UIAxes,'off');
        end
        
        function mesh_ROI_Fcn(app)

            % plot 2D pancake
            S2 = app.skinS;   S2.vertices(:,2) = [];
            P = patch(app.UIAxes_2,'faces',S2.faces,'vertices',S2.vertices,'FaceColor',[0.9 1 0.99],'FaceAlpha',0.6);camlight(app.UIAxes_2);hold(app.UIAxes_2, 'on');
            axis(app.UIAxes_2,'image'); axis(app.UIAxes_2,'off');
            update_title( app , 'Draw a polyline below which faces are not considered. Double-click when done.' );
            subtitle(app.UIAxes_2,' ');

            % draw polyline
            PLINE = drawpolyline(app.UIAxes_2);  % more complex polyline
            app.PLINE_info = PLINE.Position;

            % ROI_line = drawline(app.UIAxes_2);    % simple line
            % PN = ROI_line.Position;
            % app.ROI_slope = (PN(4)-PN(3))./(PN(2)-PN(1));  
            % app.ROI_isc = PN(3)-app.ROI_slope*PN(1); 

            % remove faces below the line
            remove_faces_belowLine(app);

            % delete current plot objects
            delete( PLINE );
            delete( P );

            % update mesh plot
            plot_head_mesh(app)

            % update normals
            update_normals(app);

            % update text boxes
            update_title( app , '');
            update_info( app , sprintf('%d faces (recommended range: 1000-4000)',size(app.CENTER,1)) );

        end     

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseheadmaskButton
        function BrowseheadmaskButtonPushed(app, event)
            [ file path ] = uigetfile([app.default_file_path '/*.mat']);  % load 
            app.default_file_path = path;
            tmp = load( [ path file ] );
            app.hmask = double(tmp.hmask);

            drawnow;
            figure(app.UIFigure)
            app.Lamp.Color = [0 1 0] ;
        end

        % Button pushed function: BrowseporosityButton
        function BrowseporosityButtonPushed(app, event)
            [ file path ] = uigetfile([app.default_file_path '/*.mat']);  % load 
            app.default_file_path = path;
            tmp = load( [ path file ] );
            app.poro = double(tmp.poro);
                        
            %%% to avoid app going into background
            drawnow; 
            figure(app.UIFigure)
            app.Lamp_2.Color = [0 1 0] ;

        end

        % Button pushed function: BrowseASEGButton
        function BrowseASEGButtonPushed(app, event)
            [ file path ] = uigetfile([app.default_file_path '/*.mat']);  % load 
            app.default_file_path = path;
            tmp = load( [ path file ] );
            app.aseg = int32(tmp.aseg);

            drawnow; 
            figure(app.UIFigure)
            app.Lamp_3.Color = [0 1 0] ;
        end

        % Selection changed function: DisplayImageButtonGroup
        function DisplayImageButtonGroupSelectionChanged(app, event)
            app.selectedButton = app.DisplayImageButtonGroup.SelectedObject.Value;
            switch app.DisplayImageButtonGroup.SelectedObject.Text
                    case 'Head mask' 
                        update_plot(app,app.hmask)
                    case 'Porosity'
                        update_plot(app,app.poro)
                    case 'Aseg'
                        update_plot(app,app.aseg)
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.slidVal = app.Slider.Value;
            switch app.DisplayImageButtonGroup.SelectedObject.Text
                    case 'Head mask' 
                        update_plot(app,app.hmask)
                    case 'Porosity'
                        update_plot(app,app.poro)
                    case 'Aseg'
                        update_plot(app,app.aseg)

            end
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
        end

        % Button pushed function: GenerateHeadMeshButton
        function GenerateHeadMeshButtonPushed(app, event)

                addpath( genpath( 'Library/' ) );
                addpath( genpath( 'iso2mesh/' ) );
                
                removeNormals(app);

                % delete existing mesh objects
                delete( app.mesh_plot );

                update_message( app , 'Generating scalp mesh, please wait...' ); pause(0.1);

                [ nx  ny  nz ] = size( app.hmask );
                dx = 0.001;  % resolution in mm, assume 1mm
                Lx = nx * dx;
                Ly = ny * dx;
                Lz = nz * dx;
                x = linspace( -(Lx-dx)/2 , (Lx-dx)/2 , nx )'; 
                y = linspace( -(Ly-dx)/2 , (Ly-dx)/2 , ny )'; 
                z = linspace( -(Lz-dx)/2 , (Lz-dx)/2 , nz )'; 
                
                app.Xg = x; app.Yg = y; app.Zg = z;

                % extract skin surface
                MASK3 = smooth3( app.hmask , 'box' , 3 );
                S = isosurface( x , y , z , MASK3 , 0.5 );

                [ V2   F2 ] = remesher( S.vertices,  S.faces, app.edge_length * 0.001 , 5 );
                S1.vertices = V2;
                S1.faces = F2; 
                
                % only keep the largest surface
                tmp = finddisconnsurf( S1.faces );
                nmax = -1;  iimax = -1;
                    for ii = 1 : numel(tmp)
                        if size(tmp{ii},1) > nmax
                            nmax = size(tmp{ii},1);
                            iimax = ii;
                        end
                    end
                S1.faces = tmp{iimax};

                app.skinS = S1;

                app.USpos.faces = S1.faces;
                app.USpos.vertices = S1.vertices;

                if ~isempty( app.PLINE_info )
                    remove_faces_belowLine(app);
                end

                plot_head_mesh(app);
                update_normals(app);

                update_info( app , sprintf('%d faces in the mesh (recommended range: 1000-4000)',size(app.CENTER,1)) );
                update_message( app , '' ); 
        end

        % Button pushed function: RemoveinvalidfacesButton
        function RemoveinvalidfacesButtonPushed(app, event)
                 cla(app.UIAxes_2,'reset');
                 mesh_ROI_Fcn(app)
        end

        % Value changed function: ShowNormalsCheckBox
        function ShowNormalsCheckBoxValueChanged(app, event)
            value = app.ShowNormalsCheckBox.Value;
            app.showNOR = value;

            if value == 1
                showNormals(app);
            else
                removeNormals(app);
            end

        end

        % Value changed function: InvertNormalsCheckBox
        function InvertNormalsCheckBoxValueChanged(app, event)
            value = app.InvertNormalsCheckBox.Value;
            app.NOR = -app.NOR;
            if isvalid(app.normals_plot)
                removeNormals(app);
                showNormals(app);
            end
        end

        % Drop down opening function: ChooseTransducerModelDropDown
        function ChooseTransducerModelDropDownOpening(app, event)
            US_type = app.ChooseTransducerModelDropDown.Value;
        end

        % Value changed function: ChooseTransducerModelDropDown
        function ChooseTransducerModelDropDownValueChanged(app, event)

            US_type = app.ChooseTransducerModelDropDown.Value;

            switch US_type
                case "BrainSonix BX Pulsar 1002 (80 mm)"
                    app.transd_params = [80 60 3 650];                    
                case "BrainSonix BX Pulsar 1002 (65 mm)"
                    app.transd_params = [65 60 3 650];
                case "BrainSonix BX Pulsar 1002 (55 mm)"
                    app.transd_params = [55 60 3 650];
            end

            % update app fields
            app.FocalDistancemEditField.Value =  app.transd_params(1);
            app.ApartureRadiusmEditField.Value = app.transd_params(2);
            app.ThicknessmEditField.Value =      app.transd_params(3);
            app.FrequencyKHzEditField.Value =    app.transd_params(4);
            app.EditField.Value = 'Transducer defined!';
            app.EditField.FontColor = [1 0 0];
        end

        % Value changed function: ThicknessmEditField
        function ThicknessmEditFieldValueChanged(app, event)
            value = app.ThicknessmEditField.Value;
            app.transd_params(3) = value;
        end

        % Value changed function: ApartureRadiusmEditField
        function ApartureRadiusmEditFieldValueChanged(app, event)
            value = app.ApartureRadiusmEditField.Value;
            app.transd_params(2) = value;
        end

        % Value changed function: FrequencyKHzEditField
        function FrequencyKHzEditFieldValueChanged(app, event)
            value = app.FrequencyKHzEditField.Value;
            app.transd_params(4) = value;
        end

        % Value changed function: FocalDistancemEditField
        function FocalDistancemEditFieldValueChanged(app, event)
            value = app.FocalDistancemEditField.Value;
            app.transd_params(1) = value;
        end

        % Value changed function: NumberofpararellprocessingunitsEditField
        function NumberofpararellprocessingunitsEditFieldValueChanged(app, event)
            app.ppool = app.NumberofpararellprocessingunitsEditField.Value;
        end

        % Value changed function: EdgelengthmmEditField
        function EdgelengthmmEditFieldValueChanged(app, event)
            value = app.EdgelengthmmEditField.Value;
            app.edge_length = str2num( value );
        end

        % Value changed function: 
        % SimulationfoldernamefullpathpreferredEditField
        function SimulationfoldernamefullpathpreferredEditFieldValueChanged(app, event)
            value = app.SimulationfoldernamefullpathpreferredEditField.Value;
            app.output_folder = value;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)

            addpath( genpath( './mSOUNDv03' ) );

            % check that all params are properly defined
            run = 1;

            if app.transd_params(1) * app.transd_params(2) * app.transd_params(3) * app.transd_params(4) == 0
               update_message( app , 'Transducer not defined!' ); 
               run = 0;
            end
            
            if isempty( app.USpos )
               update_message( app , 'Head mesh not valid!' ); 
               run = 0;
            end

            if run == 0
                return;
            end

            if exist( app.output_folder , 'dir' )
                update_message( app , sprintf('Using existing output folder %s!',app.output_folder) );
            else
                mkdir( app.output_folder );
            end

            update_message( app , 'Saving app data...' );
            skinS = app.skinS;
            Xg = app.Xg; Yg = app.Yg; Zg = app.Zg;
            USpos = app.USpos;
            hmask = app.hmask;
            poro_vol = app.poro;
            aseg = app.aseg;     
            CENTER = app.CENTER;  NOR = app.NOR;
            PLINE_info = app.PLINE_info;
            edge_length = app.edge_length;
            transd_params = app.transd_params;
            ppool = app.ppool;
            ainds = app.ainds;
            alabels = app.alabels;
            eval( sprintf( 'save %s -v7.3  skinS Xg Yg Zg USpos hmask poro_vol aseg CENTER NOR PLINE_info edge_length transd_params ppool ainds alabels' , [ app.output_folder '/app_data.mat' ] ) );

            update_message( app , 'Setting up mSOUND...' );

            % simulation parameters
            CENTER_mv = app.CENTER  +  (app.transd_params(3)/1000) * app.NOR;  %  transducer is away from scalp
            NOR = -app.NOR;  % normal MUST posint toward the brain

            % background values
            c_brain = 1546;
            rho_brain = 1046;
            
            % transducer model
            TR_focus = app.transd_params(1) / 1000;    
            TR_radius = app.transd_params(2)/2 / 1000;   
            FREQ = app.transd_params(4) * 1000;	    
            
            % mSOUND computation domain size
            LX = 2 * TR_radius * 1.3;
            LAM = c_brain / FREQ;  
            RES = LAM / 5;
            NX = ceil( LX / RES );   % resolution is ~lambda/5 while ensuring that the # of transverse pixels is odd
            if mod(NX,2) == 0
                NX = NX + 1;
            end
            RES = LX / NX;
            
            LZ = 2 * TR_focus;
            NZ = ceil( LZ / RES );
            LZ = NZ * RES;   % adjust LZ so that we have exactly the same resolution in XY and Z
            
            xf = linspace( -(LX-RES)/2 , (LX-RES)/2 , NX );
            yf = xf;
            zf = linspace( 0 , LZ-RES , NZ );
            [ xxf  yyf  zzf ] = ndgrid( xf , yf , zf );
            
            dx = xf(2) - xf(1);  
            dy = yf(2) - yf(1);
            dz = zf(2) - zf(1);
            
            x_length = xf(end) - xf(1) + dx;  % the "+dx" is so that the mSOUND grid matches exactly the ACOUSTIC_prop grid (the absolute position does not matter much, but the dx does...)
            y_length = yf(end) - yf(1) + dy;
            z_length = zf(end) - zf(1) + dz;
            
            mgrid = set_grid( 0 , 0 , dx , x_length , dy , y_length , dz , z_length ); 
            
            % Compute outpout pressure plane:
           
            % Analytical expression (fast, not accurate):
            % update_message( app , 'Computing transducer output pressure plane (analytical), please wait...' );
            % source_p = compute_source_AN( app , mgrid , TR_focus , TR_radius , c_brain , FREQ  );

            % Rayleigh-Sommerfeld (slow, accurate):
            update_message( app , 'Computing transducer output pressure plane (Rayleigh-Sommerfeld), please wait...' );
            source_p = compute_source_RS( app , mgrid , TR_focus , TR_radius , c_brain , rho_brain , FREQ  );

            medium.c0 = c_brain;  % speed of sound [m/s]
            medium.rho0 = rho_brain;  % density [kg/m^3]
            medium.beta0 = 0.0;  % non-linear coeficient 
            medium.ca0 = 1.0;  % alpha coeficient [dB/(MHz*cm)], ~1 for water, ~20 for bone
            medium.cb0 = 1.1;  % attenuation power exponent, *** CANNOT BE EXACTLY 1.0 ***
            
            medium.c    = medium.c0 * ones( mgrid.num_x , mgrid.num_y , mgrid.num_z );
            medium.rho  = medium.rho0 * ones( mgrid.num_x , mgrid.num_y , mgrid.num_z );
            medium.beta = medium.beta0 * ones( mgrid.num_x , mgrid.num_y , mgrid.num_z );
            medium.ca   = medium.ca0 * ones( mgrid.num_x , mgrid.num_y , mgrid.num_z );
            medium.cb   = medium.cb0 * ones( mgrid.num_x , mgrid.num_y , mgrid.num_z );
            
            % absorbing layer
            medium.NRL_gamma = 0.1;
            medium.NRL_alpha = 0.3;
            
            reflection_order = 0;
            
            Ntrans = size(CENTER_mv ,1);

            update_message( app , sprintf('Starting parallel Matlab pool (%d processors)...',app.ppool) ); pause(0.1);
            PO = parpool(app.ppool);
            
            indb = find(app.hmask);
            Npix = numel(indb);
            I_INT = zeros( Ntrans , Npix );
           
            T1 = datetime();

            ppm = ParforProgressbar( Ntrans, 'progressBarUpdatePeriod' , 30 , 'title' , '' );
            update_message( app , sprintf('Running %d mSOUND processes on %d processors...',Ntrans,app.ppool) ); pause(0.1);

            % save input simulation data
            eval( sprintf( 'save %s -v7.3  CENTER_mv  NOR  Xg  Yg  Zg  poro_vol  mgrid  medium  source_p  FREQ  reflection_order  TR_focus  TR_radius  FREQ  indb  Npix  Ntrans' , [ app.output_folder '/mSOUND_inputs.mat' ] ) );

            parfor ii = 1 : Ntrans
                [ II  PP  poro  c  rho  alpha ] = GUI_solve_single_transd( CENTER_mv(ii,:) , NOR(ii,:) , Xg , Yg , Zg , poro_vol , mgrid , medium , source_p , FREQ , reflection_order );
                I_INT( ii , : ) = II(indb);
                ppm.increment();
            end
            
            T2 = datetime();
            update_message( app , sprintf('Pre-calculation done in %3.0f minutes!',minutes(T2 - T1)) );
            delete(ppm);


            % beam profiles
            values = [ 0.05 : 0.3 : 1 ];  % isosurface values of the intensity to display
            I_max = zeros( Ntrans , 1 );
            Vertices = cell( Ntrans , numel(values) );
            Faces    = cell( Ntrans , numel(values) );
            update_message( app , sprintf('Meshing %d beam profiles on %d processors...' , Ntrans , app.ppool ) );
            ppm = ParforProgressbar( Ntrans, 'progressBarUpdatePeriod' , 10 , 'title' , '' );
            [ nx  ny  nz ]= size(app.hmask); 
            parfor ii = 1 : Ntrans
                I_ = zeros( nx , ny , nz );
                I_(indb) = I_INT( ii , : );
                I_max(ii) = max(I_(:));
                verts = cell(numel(values),1);
                faces = cell(numel(values),1);
                for jj = 1 : numel(values)
                    tmp = isosurface( Xg , Yg , Zg , I_ , values(jj) * I_max(ii) );        
                    if size(tmp.faces,1) > 500
                        [ VV  FF ]   = remesher( tmp.vertices ,  tmp.faces , 0.001 , 1 );  % mesh beam with 1mm resolution
                    else
                        VV = tmp.vertices;
                        FF = tmp.faces;
                    end
                    verts{jj} = VV;
                    faces{jj} = FF;
                end
                Vertices( ii , : ) = verts;
                Faces( ii , : )    = faces;
                ppm.increment();
            end
            T3 = datetime();
            eval( sprintf( 'save %s -v7.3  Vertices  Faces  I_max  values' , [ app.output_folder '/SOL_Beams.mat' ] ) );
            delete(ppm);
            delete(PO);

            % compute scalp maps
            scalpMaps_faces = zeros( size(app.USpos.faces,1)    , numel(app.ainds) );
            scalpMaps_verts = zeros( size(app.USpos.vertices,1) , numel(app.ainds) );
            asegSurf = cell( numel(app.ainds) , 1 );
            FIG = figure;
            for ii = 1 : numel(app.ainds)
                
                update_message( app , sprintf('Calculating scalp maps %d/%d (%s)...' , ii , numel(app.ainds) , strrep( app.alabels{ii} , '_' , ' ' ) ) );
                pause(0.1);

                % raw scalp map
                ind = find( app.aseg(indb) == app.ainds(ii) );
                power = squeeze( mean( I_INT(:,ind) , 2 ) );

                % smooth across neighborhing faces
                scalpMaps_faces( : , ii )  = smooth_faces( app.USpos.faces , app.USpos.vertices , power ); 

                % interpolate faces -> vertices
                scalpMaps_verts( : , ii ) = interp_face2vert( app.USpos.faces , app.USpos.vertices , scalpMaps_faces( : , ii ) , unique(app.USpos.faces(:)) );

                % plot scalp map
                figure(FIG); clf(FIG);
                patch( app.USpos  ,'FaceVertexCData' , scalpMaps_verts(:,ii) , 'FaceColor' , 'interp' , 'Edgecolor' , 'k' , 'FaceAlpha' , 1 );   
                axis image off;  material dull; view([30 0]); title( sprintf('%s',strrep( app.alabels{ii} , '_' , ' ' ) ) ); 
                pause(0.1); hold on;

                % compute aseg surface mesh
                tmp = isosurface( app.Xg , app.Yg , app.Zg , smooth3( double(app.aseg==app.ainds(ii)) , 'box' , 3 ) , 0.5 );
                [ asegSurf{ii}.vertices  asegSurf{ii}.faces ]   = remesher( tmp.vertices ,  tmp.faces , 0.001 , 5 );

                P = patch( asegSurf{ii} , 'EdgeColor' , 'none' , 'FaceColor' , 'red' , 'FaceAlpha' , 1 );
                pause(0.1);

            end
            T4 = datetime();
            close(FIG);
            eval( sprintf( 'save %s -v7.3  scalpMaps_faces  scalpMaps_verts  asegSurf  ainds  alabels  aseg' , [ app.output_folder '/SOL_ScalpMaps.mat' ] ) );            
            delete I_INT;

            update_message( app , sprintf( 'All processes finished!     mSOUND=%3.0fmin    mesh beams=%3.0fmin    scalp maps=%3.0fmin' , minutes(T2-T1) , minutes(T3-T2) , minutes(T4-T3) ) );

        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'parula');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadDataPanel = uipanel(app.UIFigure);
            app.LoadDataPanel.Title = 'Load Data                                                      ';
            app.UIAxes = uiaxes(app.LoadDataPanel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTickLabel = '';
            app.BrowseheadmaskButton = uibutton(app.LoadDataPanel, 'push');
            app.BrowseheadmaskButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseheadmaskButtonPushed, true);
            app.BrowseheadmaskButton.Text = 'Browse head mask';
            app.Label_3 = uilabel(app.LoadDataPanel);
            app.Label_3.Text = '1';
            app.Slider = uislider(app.LoadDataPanel);
            app.Slider.Limits = [0 255];
            app.Slider.MajorTicks = [];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.MinorTicks = [];
            app.Slider.Value = 127;
            app.BrowseporosityButton = uibutton(app.LoadDataPanel, 'push');
            app.BrowseporosityButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseporosityButtonPushed, true);
            app.BrowseporosityButton.Text = 'Browse porosity';
            app.DisplayImageButtonGroup = uibuttongroup(app.LoadDataPanel);
            app.DisplayImageButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DisplayImageButtonGroupSelectionChanged, true);
            app.DisplayImageButtonGroup.Title = 'Display Image';
            app.HeadmaskButton = uiradiobutton(app.DisplayImageButtonGroup);
            app.HeadmaskButton.Text = 'Head mask';
            app.HeadmaskButton.Value = true;
            app.PorosityButton = uiradiobutton(app.DisplayImageButtonGroup);
            app.PorosityButton.Text = 'Porosity';
            app.AsegButton = uiradiobutton(app.DisplayImageButtonGroup);
            app.AsegButton.Text = 'Aseg';
            app.BrowseASEGButton = uibutton(app.LoadDataPanel, 'push');
            app.BrowseASEGButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseASEGButtonPushed, true);
            app.BrowseASEGButton.Text = 'Browse ASEG';
            app.Lamp = uilamp(app.LoadDataPanel);
            app.Lamp_2 = uilamp(app.LoadDataPanel);
            app.Lamp_3 = uilamp(app.LoadDataPanel);
            app.ChooseTransducerModelPanel = uipanel(app.UIFigure);
            app.ChooseTransducerModelPanel.Title = 'Choose Transducer Model';
            app.orLabel = uilabel(app.ChooseTransducerModelPanel);
            app.orLabel.Text = 'or ';
            app.DefineTransducerParametersLabel = uilabel(app.ChooseTransducerModelPanel);
            app.DefineTransducerParametersLabel.Text = 'Define Transducer Parameters';
            app.ChooseTransducerModelDropDown = uidropdown(app.ChooseTransducerModelPanel);
            app.ChooseTransducerModelDropDown.Items = {'-', 'BrainSonix BX Pulsar 1002 (80 mm)', 'BrainSonix BX Pulsar 1002 (65 mm)', 'BrainSonix BX Pulsar 1002 (55 mm)'};
            app.ChooseTransducerModelDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ChooseTransducerModelDropDownOpening, true);
            app.ChooseTransducerModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseTransducerModelDropDownValueChanged, true);
            app.ChooseTransducerModelDropDown.Value = '-';
            app.FocalDistancemEditFieldLabel = uilabel(app.ChooseTransducerModelPanel);
            app.FocalDistancemEditFieldLabel.Text = 'Focal Distance [mm]';
            app.FocalDistancemEditField = uieditfield(app.ChooseTransducerModelPanel, 'numeric');
            app.FocalDistancemEditField.ValueChangedFcn = createCallbackFcn(app, @FocalDistancemEditFieldValueChanged, true);
            app.ApartureRadiusmEditFieldLabel = uilabel(app.ChooseTransducerModelPanel);
            app.ApartureRadiusmEditFieldLabel.Text = 'Aperture Diameter [mm]';
            app.ApartureRadiusmEditField = uieditfield(app.ChooseTransducerModelPanel, 'numeric');
            app.ApartureRadiusmEditField.ValueChangedFcn = createCallbackFcn(app, @ApartureRadiusmEditFieldValueChanged, true);
            app.ThicknessmEditFieldLabel = uilabel(app.ChooseTransducerModelPanel);
            app.ThicknessmEditFieldLabel.Text = 'Distance to Scalp [mm]';
            app.ThicknessmEditField = uieditfield(app.ChooseTransducerModelPanel, 'numeric');
            app.ThicknessmEditField.ValueChangedFcn = createCallbackFcn(app, @ThicknessmEditFieldValueChanged, true);
            app.Label = uilabel(app.ChooseTransducerModelPanel);
            app.Label.Text = '3';
            app.FrequencyKHzLabel = uilabel(app.ChooseTransducerModelPanel);
            app.FrequencyKHzLabel.Text = 'Frequency [KHz]';
            app.FrequencyKHzEditField = uieditfield(app.ChooseTransducerModelPanel, 'numeric');
            app.FrequencyKHzEditField.ValueChangedFcn = createCallbackFcn(app, @FrequencyKHzEditFieldValueChanged, true);
            app.RunAcousticIntensityCalculationsPanel = uipanel(app.UIFigure);
            app.RunAcousticIntensityCalculationsPanel.Title = 'Run Acoustic Intensity Calculations';
            app.Label_2 = uilabel(app.RunAcousticIntensityCalculationsPanel);
            app.Label_2.Text = '4';
            app.RunButton = uibutton(app.RunAcousticIntensityCalculationsPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.NumberofpararellprocessingunitsEditFieldLabel = uilabel(app.RunAcousticIntensityCalculationsPanel);
            app.NumberofpararellprocessingunitsEditFieldLabel.Text = 'Number of pararell processing units';
            app.NumberofpararellprocessingunitsEditField = uieditfield(app.RunAcousticIntensityCalculationsPanel, 'numeric');
            app.NumberofpararellprocessingunitsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofpararellprocessingunitsEditFieldValueChanged, true);
            app.NumberofpararellprocessingunitsEditField.Value = 10;
            app.SimulationfoldernamefullpathpreferredEditFieldLabel = uilabel(app.RunAcousticIntensityCalculationsPanel);
            app.SimulationfoldernamefullpathpreferredEditFieldLabel.Text = 'Simulation folder name (full path preferred)';
            app.SimulationfoldernamefullpathpreferredEditField = uieditfield(app.RunAcousticIntensityCalculationsPanel, 'text');
            app.SimulationfoldernamefullpathpreferredEditField.ValueChangedFcn = createCallbackFcn(app, @SimulationfoldernamefullpathpreferredEditFieldValueChanged, true);
            app.SimulationfoldernamefullpathpreferredEditField.Value = 'Simulation_test_001';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.GenerateMeshPanel = uipanel(app.UIFigure);
            app.GenerateMeshPanel.Title = 'Generate Mesh';
            app.UIAxes_2 = uiaxes(app.GenerateMeshPanel);
            app.UIAxes_2.XTickLabel = '';
            app.UIAxes_2.YTickLabel = '';
            app.UIAxes_2.Visible = 'off';
            app.GenerateHeadMeshButton = uibutton(app.GenerateMeshPanel, 'push');
            app.GenerateHeadMeshButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateHeadMeshButtonPushed, true);
            app.GenerateHeadMeshButton.Text = 'Generate Head Mesh';
            app.RemoveinvalidfacesButton = uibutton(app.GenerateMeshPanel, 'push');
            app.RemoveinvalidfacesButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveinvalidfacesButtonPushed, true);
            app.RemoveinvalidfacesButton.Text = 'Remove invalid faces';
            app.Label_4 = uilabel(app.GenerateMeshPanel);
            app.Label_4.Text = '2';
            app.ShowNormalsCheckBox = uicheckbox(app.GenerateMeshPanel);
            app.ShowNormalsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowNormalsCheckBoxValueChanged, true);
            app.ShowNormalsCheckBox.Text = 'Show Normals';
            app.InvertNormalsCheckBox = uicheckbox(app.GenerateMeshPanel);
            app.InvertNormalsCheckBox.ValueChangedFcn = createCallbackFcn(app, @InvertNormalsCheckBoxValueChanged, true);
            app.InvertNormalsCheckBox.Text = 'Invert Normals';
            app.InfoLabel = uilabel(app.GenerateMeshPanel);
            app.InfoLabel.Text = 'Info';
            app.Step1GeneratescalpmeshLabel = uilabel(app.GenerateMeshPanel);
            app.Step1GeneratescalpmeshLabel.Text = 'Step 1) Generate scalp mesh';
            app.Step3MakesurenormalspointawayfromheadLabel = uilabel(app.GenerateMeshPanel);
            app.Step3MakesurenormalspointawayfromheadLabel.Text = 'Step 3) Make sure normals point away from head';
            app.Step2RemovefacesbeloweyesearsLabel = uilabel(app.GenerateMeshPanel);
            app.Step2RemovefacesbeloweyesearsLabel.Text = 'Step 2) Remove faces below eyes/ears';
            app.EdgelengthmmEditFieldLabel = uilabel(app.GenerateMeshPanel);
            app.EdgelengthmmEditFieldLabel.Text = 'Edge length [mm]';
            app.EdgelengthmmEditField = uieditfield(app.GenerateMeshPanel, 'text');
            app.EdgelengthmmEditField.ValueChangedFcn = createCallbackFcn(app, @EdgelengthmmEditFieldValueChanged, true);
            app.EdgelengthmmEditField.Value = '8';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = precalc_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end